classdef LoomoDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConnectionPanel                matlab.ui.container.Panel
        ConnectButton                  matlab.ui.control.StateButton
        ConnectionLamp                 matlab.ui.control.Lamp
        LoomoIpLabel                   matlab.ui.control.Label
        LoomoIpEditField               matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        PortEditFieldLabel             matlab.ui.control.Label
        PortEditField                  matlab.ui.control.NumericEditField
        LooptimeLabel                  matlab.ui.control.Label
        latencyLabel                   matlab.ui.control.Label
        VisionPanel                    matlab.ui.container.Panel
        ColorCheckBox                  matlab.ui.control.CheckBox
        DepthCheckBox                  matlab.ui.control.CheckBox
        ColorLargeCheckBox             matlab.ui.control.CheckBox
        PointcloudslowCheckBox         matlab.ui.control.CheckBox
        ResponciveCheckBox             matlab.ui.control.CheckBox
        EnableVisionButton             matlab.ui.control.StateButton
        ActiveLampLabel                matlab.ui.control.Label
        ActiveLamp                     matlab.ui.control.Lamp
        DrivePanel                     matlab.ui.container.Panel
        EnabledriveSwitchLabel         matlab.ui.control.Label
        EnabledriveSwitch              matlab.ui.control.Switch
        DriveLamp                      matlab.ui.control.Lamp
        UltrasonicandIRPanel           matlab.ui.container.Panel
        LeftGaugeLabel                 matlab.ui.control.Label
        LeftGauge                      matlab.ui.control.LinearGauge
        FrontGaugeLabel                matlab.ui.control.Label
        FrontGauge                     matlab.ui.control.LinearGauge
        RightGaugeLabel                matlab.ui.control.Label
        RightGauge                     matlab.ui.control.LinearGauge
        Velocitypulse700msPanel        matlab.ui.container.Panel
        LinearVelocityEditFieldLabel   matlab.ui.control.Label
        LinearVelocityEditField        matlab.ui.control.NumericEditField
        AngularVelocityEditFieldLabel  matlab.ui.control.Label
        AngularVelocityEditField       matlab.ui.control.NumericEditField
        SendVelocityButton             matlab.ui.control.Button
        SetheadpositionPanel           matlab.ui.container.Panel
        PitchEditFieldLabel            matlab.ui.control.Label
        PitchEditField                 matlab.ui.control.NumericEditField
        YawEditFieldLabel              matlab.ui.control.Label
        YawEditField                   matlab.ui.control.NumericEditField
        SendHeadPositionButton         matlab.ui.control.Button
        Panel                          matlab.ui.container.Panel
        VelocityGaugeLabel             matlab.ui.control.Label
        VelocityGauge                  matlab.ui.control.SemicircularGauge
        TurnRateGaugeLabel             matlab.ui.control.Label
        TurnRateGauge                  matlab.ui.control.SemicircularGauge
        KeyboardControlPanel           matlab.ui.container.Panel
        KeyboardVelocityButton         matlab.ui.control.StateButton
        PitchLabel                     matlab.ui.control.Label
        YawLabel                       matlab.ui.control.Label
        VelocityLabel                  matlab.ui.control.Label
        TurnRateLabel                  matlab.ui.control.Label
        ShowPitch                      matlab.ui.control.Label
        ShowYaw                        matlab.ui.control.Label
        ShowVel                        matlab.ui.control.Label
        ShowTurn                       matlab.ui.control.Label
        SpeakPanel                     matlab.ui.container.Panel
        SpeakTextArea                  matlab.ui.control.TextArea
        SendButton                     matlab.ui.control.Button
        VolumeSliderLabel              matlab.ui.control.Label
        VolumeSlider                   matlab.ui.control.Slider
        ImageRight                     matlab.ui.control.Image
        ImageRightDropDown_2Label      matlab.ui.control.Label
        ImageRightDropDown             matlab.ui.control.DropDown
        GotopositionPanel              matlab.ui.container.Panel
        GoButton                       matlab.ui.control.Button
        XEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YawLabel_2                     matlab.ui.control.Label
        YawEditField_pos               matlab.ui.control.NumericEditField
        CheckBox                       matlab.ui.control.CheckBox
        ImageLeft                      matlab.ui.control.Image
        ImageLeftDropDownLabel         matlab.ui.control.Label
        ImageLeftDropDown              matlab.ui.control.DropDown
        SensorstoReadPanel             matlab.ui.container.Panel
        VelocityPoseButton             matlab.ui.control.StateButton
        SurroundingsButton             matlab.ui.control.StateButton
        BaseIMUButton                  matlab.ui.control.StateButton
        HeadIMUButton                  matlab.ui.control.StateButton
        BasePoseandIMUreadingPanel     matlab.ui.container.Panel
        XLabel                         matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        ThLabel                        matlab.ui.control.Label
        xPoseLabel                     matlab.ui.control.Label
        yPoseLabel                     matlab.ui.control.Label
        thPoseLabel                    matlab.ui.control.Label
        RollLabel                      matlab.ui.control.Label
        PitchLabel_2                   matlab.ui.control.Label
        YawLabel_3                     matlab.ui.control.Label
        thPoseLabel_2                  matlab.ui.control.Label
        thPoseLabel_3                  matlab.ui.control.Label
        thPoseLabel_4                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        loomo                   BackgroundLoomoConnector    % Loomo connection handle
        isConnected = false                                 % Indicate connection status
        MemoryTimer                                         % Timer handle
        loopTimerLast = 0;
        loopTime = 0;                                       % Looptime in s
        
        frameWorkPath = '../';
        
        
        % Loomo drive modes
        driveOnKeys = false;
        
        
        % Sensor readings
        velocityLinearActuall = 0;
        velocityAngularActuall = 0;
        irLeft = 0;
        irRight = 0; 
        ultrasonicFront = 0; % Front distance in m
        
        % Loomo controll values
        defaultHeadPitchRate = 30*pi/180;
        defaultHeadYawRate = 30*pi/180;
        defaultHeadPitch = pi/5;
        defaultHeadYaw = 0;
        defaultVelocity = 0.5; %ms
        defaultTurnRate = 0.5; %ms
        keyHeadPitch = pi/5; % Integrated Head Position
        keyHeadYaw = 0;
        keyVelocity = 0; % Desired velocity on key
        keyTurnRate = 0; % Desired trun rate on key
        
        
        % Keyboard controll
        newkeyVelocity = false;
        newKeyHead = false;
        keyUP = 1;
        keyDOWN = 2;
        keyLEFT = 3;
        keyRIGHT = 4;
        
        keySpace = false;
        keyBaseVelocity = [false, false, false, false]; % Forward, Reverse, Left, Right
        keyHeadPositionShift = [false, false, false, false]; % Up, Down, Left, Right
    end
    
    methods (Access = private)
        
        function changeDriveState(app,on)
           %%app.loomo.enableDrive(on)
           
           app.loomo.newEnableDrive = true;
           app.loomo.enableDriveValue = on;
           
           app.SendVelocityButton.Enable = on;
           app.KeyboardVelocityButton.Enable = on;
           if on
               app.DriveLamp.Color = [0,1,0];
           else
               app.DriveLamp.Color = [1,0,0];
           end
        end
   
        function on = changeVisionState(app, on)
            color = app.ColorCheckBox.Value;
            colorLarge = app.ColorLargeCheckBox.Value;
            depth = app.DepthCheckBox.Value;
            onArray = [color,colorLarge, depth];
            
            anyOn = any(onArray);
            on = on && anyOn;
            app.loomo.newEnableImages = true;
            if on
                %app.loomo.enableVision(color,colorLarge,depth)
                app.loomo.colorFast = color;
                app.loomo.colorLarge = colorLarge;
                app.loomo.depth = depth;
                app.ActiveLamp.Color = [0,1,0]; 
                
                colorDropDown = {'Select image', 'Color fast', 'Color Large', 'Depth'};
                colorValue = [9,1,2,3];
                colorDropDown([false, ~onArray]) = [];
                 colorValue([false, ~onArray]) = [];
                app.ImageRightDropDown.Enable = true;
            else
                %app.loomo.enableVision(false,false,false)
                app.loomo.colorFast = false;
                app.loomo.colorLarge = false;
                app.loomo.depth = false;
                app.ActiveLamp.Color = [1,0,0];
                colorDropDown = {'Not available'};
                colorValue = 9;
                app.ImageRightDropDown.Enable = false;
                app.changeImageDisplay(9);
                app.changeImageDisplay2(9);
            end
            
            app.ImageRightDropDown.Items = colorDropDown;
            app.ImageRightDropDown.ItemsData = colorValue;
            app.ImageLeftDropDown.Items = colorDropDown;
            app.ImageLeftDropDown.ItemsData = colorValue;
            app.ColorCheckBox.Enable = ~on;
            app.ColorLargeCheckBox.Enable = ~on;
            app.DepthCheckBox.Enable = ~on;
            app.ResponciveCheckBox.Enable = ~on;
            app.PointcloudslowCheckBox.Enable = ~on;
        end
    
        function changeImageDisplay(app,value)
            if value ~= 9
                disp(['Get image: ',num2str(value)])
                app.loomo.getImage = value;
            else
                disp('No image')
                app.loomo.getImage = 0;
            end
        end
        function changeImageDisplay2(app,value)
            if value ~= 9
                disp(['Get image2: ',num2str(value)])
                app.loomo.getImage2 = value;
            else
                disp('No image2')
                app.loomo.getImage2 = 0;
            end
        end
        
        function setResponciveVision(app)
            value = app.ResponciveCheckBox.Value;
            if value
                app.ColorCheckBox.Value = true;
                app.DepthCheckBox.Value = false;
                app.ColorLargeCheckBox.Value = false;
                
                app.PointcloudslowCheckBox.Value = false;
            end
            app.ColorCheckBox.Enable = ~value;
            app.ColorLargeCheckBox.Enable = ~value;
            app.DepthCheckBox.Enable = ~value;
        end
        
        function setPointCloudVision(app)
            value = app.PointcloudslowCheckBox.Value;
            if value
                app.ColorCheckBox.Value = true;
                app.DepthCheckBox.Value = true;
                app.ColorLargeCheckBox.Value = false;
                
                app.ResponciveCheckBox.Value = false;
            end
            app.ColorCheckBox.Enable = ~value;
            app.ColorLargeCheckBox.Enable = ~value;
            app.DepthCheckBox.Enable = ~value;
        end
        
        function result = connectToLoomo(app)
            result = false;
            try
                app.loomo.connect();
                
                app.isConnected = true;
                app.EnabledriveSwitch.Enable = true;
                app.EnableVisionButton.Enable = true;
                app.SendHeadPositionButton.Enable = true;
                app.ConnectionLamp.Color = [0,1,0];
                app.ConnectButton.Text = "Disconnect";
                app.LoomoIpEditField.Enable = false;
                app.PortEditField.Enable = false;


                result = true;
                
                app.loomo.runMainLoop();
                start(app.MemoryTimer);
                
            catch ME
                warning("Connection to loomo failed")
                warning(ME.message)
                app.disconnectFromLoomo();
            end
            
            
        end
    
        function disconnectFromLoomo(app)
            try
                stop(app.MemoryTimer);
                app.changeDriveState(false);
                app.changeVisionState(false);
                app.loomo.disconnect();
                
                app.isConnected = false;
                app.ConnectionLamp.Color = [1,0,0];
                app.ConnectButton.Text = "Connect";
                
                app.LoomoIpEditField.Enable = true;
                app.PortEditField.Enable = true;
            catch ME
                warning(ME.message)
            end
            app.EnabledriveSwitch.Enable = false;
            app.SendVelocityButton.Enable = false;
            app.EnableVisionButton.Enable = false;
            app.SendHeadPositionButton.Enable = false;
        end
    
        function mainLoop(app,~,~)          
            %app.updateLoopTimer();
            
            if app.loomo.isFinished()
                app.updateLoopTimer();
                if app.driveOnKeys
                    app.updatedKeyDriveValues();
                end
                app.loomo.getMainResults();
                app.loomo.runMainLoop();
                app.updateCoreInstruments();
                
                if app.loomo.getImage>0
                    try
                        if app.loomo.getImage == 3
                            imgData = app.loomo.imgData / hex2dec('FFFF');
                            imgData = cat(3,imgData,imgData,imgData);
                            app.ImageRight.ImageSource = imgData;
                        else
                            app.ImageRight.ImageSource = app.loomo.imgData;
                        end
                    catch me
                        warning(me.message)                        
                    end
                end
                if app.loomo.getImage2>0
                    try
                        if app.loomo.getImage2 == 3
                            imgData = app.loomo.imgData2 / hex2dec('FFFF');
                            imgData = cat(3,imgData,imgData,imgData);
                            app.ImageLeft.ImageSource = imgData;
                        else
                            app.ImageLeft.ImageSource = app.loomo.imgData2;
                        end
                        
                    catch me
                            warning(me.message)                        
                    end
                end
            end
            
%                 
%                 %if app.newKeyHead || app.newkeyVelocity
%                     fprintf("Velocity: %0.2f\nTurnRate: %0.2f\n\nPitch: %d\nYaw: %d\n",app.keyVelocity,app.keyTurnRate,...
%                         round(app.keyHeadPitch*180/pi,0),round(app.keyHeadYaw*180/pi,0))    
%                 %end
%             end
        end
    
        function updateCoreInstruments(app)
            
            if app.loomo.readVelocity
                app.VelocityGauge.Value = app.loomo.velocity;
                app.TurnRateGauge.Value = app.loomo.turnRate;
                app.xPoseLabel.Text = sprintf("%0.2f m", app.loomo.xBase);
                app.yPoseLabel.Text = sprintf("%0.2f m", app.loomo.yBase);
                app.thPoseLabel.Text = sprintf("%d deg", round(app.loomo.thBase*180/pi,0));
            end
            
            if app.loomo.readSurroundings
               app.LeftGauge.Value = app.loomo.ir_left/1000;
               app.RightGauge.Value = app.loomo.ir_right/1000;
               app.FrontGauge.Value = app.loomo.uss/1000;
            end
            
        end
        
        function updateLoopTimer(app)
            if app.loopTimerLast == 0
                app.loopTimerLast = tic;
                app.latencyLabel.Text = "x ms";
            else
                app.loopTime = toc(app.loopTimerLast);
                app.loopTimerLast = tic;
                
                str =sprintf("%d ms",round(app.loopTime*1000,0));
                app.latencyLabel.Text = str;
            end
        end
    
        function resetKeyReadings(app)
            app.keySpace = false;
            app.keyBaseVelocity = false(1,4);
            app.keyHeadPositionShift = false(1,4);
        end
    
        function getKeyReading(app,key)
            switch key
                case 'uparrow'
                    app.keyBaseVelocity(app.keyUP) = true;
                    app.keyBaseVelocity(app.keyDOWN) = false;
                case 'downarrow'
                    app.keyBaseVelocity(app.keyDOWN) = true;
                    app.keyBaseVelocity(app.keyUP) = false; 
                case 'leftarrow'
                    app.keyBaseVelocity(app.keyLEFT) = true;
                    app.keyBaseVelocity(app.keyRIGHT) = false;
                case 'rightarrow'
                    app.keyBaseVelocity(app.keyRIGHT) = true;
                    app.keyBaseVelocity(app.keyLEFT) = false;
                case 'w'
                    app.keyHeadPositionShift(app.keyUP) = true;
                    app.keyHeadPositionShift(app.keyDOWN) = false;
                case 's'
                    app.keyHeadPositionShift(app.keyDOWN) = true;
                    app.keyHeadPositionShift(app.keyUP) = false;
                case 'a'
                    app.keyHeadPositionShift(app.keyLEFT) = true;
                    app.keyHeadPositionShift(app.keyRIGHT) = false;
                case 'd'
                    app.keyHeadPositionShift(app.keyRIGHT) = true;
                    app.keyHeadPositionShift(app.keyLEFT) = false;
                case 'space'
                    app.resetKeyReadings();
                    %app.keySpace = true;
                    disp('Key space pressed')
            end
%             disp('Key detected')
%             disp(app.keyBaseVelocity)
%             disp(app.keyHeadPositionShift)
        end
    
        function updatedKeyDriveValues(app)
            %disp('UpdateKeyDriveValues')
            if any(app.keyBaseVelocity) || app.keySpace
                if app.keyBaseVelocity(app.keyUP) && ~app.keySpace
                    app.keyVelocity = app.defaultVelocity;
                elseif app.keyBaseVelocity(app.keyDOWN) && ~app.keySpace
                    app.keyVelocity = -app.defaultVelocity;
                else
                    %app.keyBaseVelocity = 0;
                end
                if app.keyBaseVelocity(app.keyLEFT) && ~app.keySpace
                    app.keyTurnRate = app.defaultTurnRate;
                elseif app.keyBaseVelocity(app.keyRIGHT) && ~app.keySpace
                    app.keyTurnRate = -app.defaultTurnRate;
                else
                    %app.keyTurnRate = 0;
                end
                app.newkeyVelocity = true;
            else
                app.newkeyVelocity = true;
                app.keyVelocity = 0;
                app.keyTurnRate = 0;
            end
            
                app.ShowVel.Text = sprintf("%0.2f m/s",app.keyVelocity);
                app.ShowTurn.Text = sprintf("%0.2f rad/s", app.keyTurnRate);
                app.loomo.keyVelocity = app.keyVelocity;
                app.loomo.keyTurnRate = app.keyTurnRate;
                        
            if any(app.keyHeadPositionShift) || app.keySpace
                if app.keyHeadPositionShift(app.keyUP)
                    value = app.keyHeadPitch+app.defaultHeadPitchRate;
                    if value < pi % PI beeing pitch limit of Loomo
                        app.keyHeadPitch = value;
                    end
                elseif app.keyHeadPositionShift(app.keyDOWN)
                    value = app.keyHeadPitch-app.defaultHeadPitchRate;
                    if value > -pi/2 % PI/2 beeing pitch limit of Loomo
                        app.keyHeadPitch = value;
                    end
                end
                
                if app.keyHeadPositionShift(app.keyLEFT)
                    value = app.keyHeadYaw + app.defaultHeadYawRate*app.loopTime;
                    if value < pi/1.2 % PI beeing pitch limit of Loomo
                        app.keyHeadYaw = value;
                    end
                elseif app.keyHeadPositionShift(app.keyRIGHT)
                    value = app.keyHeadYaw - app.defaultHeadYawRate*app.loopTime;
                    if value > -pi/1.2 % PI beeing pitch limit of Loomo
                        app.keyHeadYaw = value;
                    end
                end
                if app.keySpace
                    app.keyHeadYaw = app.defaultHeadYaw;
                    app.keyHeadPitch = app.defaultHeadPitch;
                end
                app.ShowPitch.Text = sprintf("%d deg",round(app.keyHeadPitch*180/pi));
                app.ShowYaw.Text = sprintf("%d deg",round(app.keyHeadYaw*180/pi));
                app.newKeyHead = true;
                app.loomo.keyHeadPitch = app.keyHeadPitch;
                app.loomo.keyHeadYaw = app.keyHeadYaw;
            else
                app.newKeyHead = false;
            end
            app.loomo.keyNewHead = app.newKeyHead;
            
            app.resetKeyReadings();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                
                addpath(app.frameWorkPath)
                
                ip = app.LoomoIpEditField.Value;
                port = app.PortEditField.Value;
                app.loomo = BackgroundLoomoConnector(ip,port);

                
                % Create timer object
            app.MemoryTimer = timer(...
                'ExecutionMode', 'fixedSpacing', ...    % Run timer repeatedly
                'Period', 0.05, ...                     % Period is 1 second
                'BusyMode', 'drop',...              % Queue timer callbacks when busy
                'TimerFcn', @app.mainLoop);          % Specify callback function
        end

        % Value changed function: EnableVisionButton
        function EnableVisionButtonValueChanged(app, event)
            value = app.EnableVisionButton.Value;
            
            if app.isConnected
               app.EnableVisionButton.Value = app.changeVisionState(value);
            else
                app.EnableVisionButton.Value = false;
                warning("Loomo is not connected: Could not activate vision")
            end
            
            
        end

        % Value changed function: ResponciveCheckBox
        function ResponciveCheckBoxValueChanged(app, event)
            app.setResponciveVision()
        end

        % Value changed function: PointcloudslowCheckBox
        function PointcloudslowCheckBoxValueChanged(app, event)
            app.setPointCloudVision()
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            
            if value
                app.ConnectButton.Value = app.connectToLoomo();
                
            else
                app.disconnectFromLoomo();
            end
        end

        % Value changed function: LoomoIpEditField
        function LoomoIpEditFieldValueChanged(app, event)
            value = app.LoomoIpEditField.Value;
            app.loomo.ip = value;
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            value = app.PortEditField.Value;
            app.loomo.port = value;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.isConnected
                app.disconnectFromLoomo();
            end
            delete(app.MemoryTimer);
            delete(app)
        end

        % Value changed function: EnabledriveSwitch
        function EnabledriveSwitchValueChanged(app, event)
            value = app.EnabledriveSwitch.Value;
            value = strcmp(value,"On");
            app.changeDriveState(value);
        end

        % Button pushed function: SendVelocityButton
        function SendVelocityButtonPushed(app, event)
            linVel = app.LinearVelocityEditField.Value;
            angVel = app.AngularVelocityEditField.Value;
            
            app.loomo.setVelocity(linVel, angVel);
        end

        % Button pushed function: SendHeadPositionButton
        function SendHeadPositionButtonPushed(app, event)
            pitch = app.PitchEditField.Value*pi/180;
            yaw = app.YawEditField.Value*pi/180;
            
            app.loomo.setHeadPosition(yaw,pitch)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            app.getKeyReading(key);
        end

        % Value changed function: KeyboardVelocityButton
        function KeyboardVelocityButtonValueChanged(app, event)
            value = app.KeyboardVelocityButton.Value;
            app.driveOnKeys = value;
            app.loomo.driveOnKeys = value;
        end

        % Value changed function: VolumeSlider
        function VolumeSliderValueChanged(app, event)
            value = app.VolumeSlider.Value;
            app.loomo.newVolume = true;
            app.loomo.volume = value/100;
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            text = app.SpeakTextArea.Value;
            if size(text)>1
                tmp = '';
                for i = 1:size(text)
                    tmp = [tmp, text{i},'. '];
                end
                text = tmp;
            else
                text = char(text);    
            end
            
            if length(text) > 1
                app.loomo.newSpeak = true;
                app.loomo.speakString = text;
            end
        end

        % Value changed function: ImageRightDropDown
        function ImageRightDropDownValueChanged(app, event)
            value = app.ImageRightDropDown.Value;
            app.changeImageDisplay(value)
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
                        
            app.ImageRight.ImageSource = app.loomo.imgData;
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            app.YawEditField_pos.Enable = value;
        end

        % Value changed function: VelocityPoseButton
        function VelocityPoseButtonValueChanged(app, event)
            value = app.VelocityPoseButton.Value;
            app.loomo.readVelocity = value;
        end

        % Value changed function: SurroundingsButton
        function SurroundingsButtonValueChanged(app, event)
            value = app.SurroundingsButton.Value;
            app.loomo.readSurroundings = value;
        end

        % Value changed function: ImageLeftDropDown
        function ImageLeftDropDownValueChanged(app, event)
            value = app.ImageLeftDropDown.Value;
            app.changeImageDisplay2(value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.ConnectButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.ConnectionLamp = uilamp(app.ConnectionPanel);
            app.LoomoIpLabel = uilabel(app.ConnectionPanel);
            app.LoomoIpLabel.Text = 'Loomo Ip';
            app.LoomoIpEditField = uieditfield(app.ConnectionPanel, 'text');
            app.LoomoIpEditField.ValueChangedFcn = createCallbackFcn(app, @LoomoIpEditFieldValueChanged, true);
            app.LoomoIpEditField.Tooltip = {'Ip adress of loomo'};
            app.LoomoIpEditField.Value = '192.168.137.84';
            app.Label = uilabel(app.ConnectionPanel);
            app.Label.Text = '/';
            app.PortEditFieldLabel = uilabel(app.ConnectionPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.ConnectionPanel, 'numeric');
            app.PortEditField.Limits = [0 65535];
            app.PortEditField.RoundFractionalValues = 'on';
            app.PortEditField.ValueDisplayFormat = '%11d';
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 1337;
            app.LooptimeLabel = uilabel(app.ConnectionPanel);
            app.LooptimeLabel.Text = 'Loop time:';
            app.latencyLabel = uilabel(app.ConnectionPanel);
            app.latencyLabel.Text = 'xx ms';
            app.VisionPanel = uipanel(app.UIFigure);
            app.VisionPanel.Title = 'Vision';
            app.ColorCheckBox = uicheckbox(app.VisionPanel);
            app.ColorCheckBox.Text = 'Color';
            app.DepthCheckBox = uicheckbox(app.VisionPanel);
            app.DepthCheckBox.Text = 'Depth';
            app.ColorLargeCheckBox = uicheckbox(app.VisionPanel);
            app.ColorLargeCheckBox.Text = 'Color Large';
            app.PointcloudslowCheckBox = uicheckbox(app.VisionPanel);
            app.PointcloudslowCheckBox.ValueChangedFcn = createCallbackFcn(app, @PointcloudslowCheckBoxValueChanged, true);
            app.PointcloudslowCheckBox.Text = 'Point cloud (slow)';
            app.ResponciveCheckBox = uicheckbox(app.VisionPanel);
            app.ResponciveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResponciveCheckBoxValueChanged, true);
            app.ResponciveCheckBox.Text = 'Responcive';
            app.EnableVisionButton = uibutton(app.VisionPanel, 'state');
            app.EnableVisionButton.ValueChangedFcn = createCallbackFcn(app, @EnableVisionButtonValueChanged, true);
            app.EnableVisionButton.Enable = 'off';
            app.EnableVisionButton.Text = 'Enable Vision';
            app.ActiveLampLabel = uilabel(app.VisionPanel);
            app.ActiveLampLabel.Text = 'Active';
            app.ActiveLamp = uilamp(app.VisionPanel);
            app.DrivePanel = uipanel(app.UIFigure);
            app.DrivePanel.Title = 'Drive';
            app.EnabledriveSwitchLabel = uilabel(app.DrivePanel);
            app.EnabledriveSwitchLabel.Text = 'Enable drive';
            app.EnabledriveSwitch = uiswitch(app.DrivePanel, 'slider');
            app.EnabledriveSwitch.ValueChangedFcn = createCallbackFcn(app, @EnabledriveSwitchValueChanged, true);
            app.EnabledriveSwitch.Enable = 'off';
            app.DriveLamp = uilamp(app.DrivePanel);
            app.UltrasonicandIRPanel = uipanel(app.UIFigure);
            app.UltrasonicandIRPanel.Title = 'Ultrasonic and IR';
            app.LeftGaugeLabel = uilabel(app.UltrasonicandIRPanel);
            app.LeftGaugeLabel.Text = 'Left';
            app.LeftGauge = uigauge(app.UltrasonicandIRPanel, 'linear');
            app.LeftGauge.Limits = [0.3 2];
            app.FrontGaugeLabel = uilabel(app.UltrasonicandIRPanel);
            app.FrontGaugeLabel.Text = 'Front';
            app.FrontGauge = uigauge(app.UltrasonicandIRPanel, 'linear');
            app.FrontGauge.Limits = [0.3 1.5];
            app.RightGaugeLabel = uilabel(app.UltrasonicandIRPanel);
            app.RightGaugeLabel.Text = 'Right';
            app.RightGauge = uigauge(app.UltrasonicandIRPanel, 'linear');
            app.RightGauge.Limits = [0.3 2];
            app.Velocitypulse700msPanel = uipanel(app.UIFigure);
            app.Velocitypulse700msPanel.Title = 'Velocity pulse (700ms)';
            app.LinearVelocityEditFieldLabel = uilabel(app.Velocitypulse700msPanel);
            app.LinearVelocityEditFieldLabel.Text = 'Linear Velocity';
            app.LinearVelocityEditField = uieditfield(app.Velocitypulse700msPanel, 'numeric');
            app.LinearVelocityEditField.Limits = [-1 1];
            app.LinearVelocityEditField.ValueDisplayFormat = '%11.4g m/s';
            app.AngularVelocityEditFieldLabel = uilabel(app.Velocitypulse700msPanel);
            app.AngularVelocityEditFieldLabel.Text = 'Angular Velocity';
            app.AngularVelocityEditField = uieditfield(app.Velocitypulse700msPanel, 'numeric');
            app.AngularVelocityEditField.Limits = [-1.5 1.5];
            app.AngularVelocityEditField.ValueDisplayFormat = '%11.2g rad/s';
            app.SendVelocityButton = uibutton(app.Velocitypulse700msPanel, 'push');
            app.SendVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @SendVelocityButtonPushed, true);
            app.SendVelocityButton.Enable = 'off';
            app.SendVelocityButton.Text = 'Send';
            app.SetheadpositionPanel = uipanel(app.UIFigure);
            app.SetheadpositionPanel.Title = 'Set head position';
            app.PitchEditFieldLabel = uilabel(app.SetheadpositionPanel);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.SetheadpositionPanel, 'numeric');
            app.PitchEditField.Limits = [-90 180];
            app.PitchEditField.RoundFractionalValues = 'on';
            app.PitchEditField.ValueDisplayFormat = '%11g Deg';
            app.YawEditFieldLabel = uilabel(app.SetheadpositionPanel);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.SetheadpositionPanel, 'numeric');
            app.YawEditField.Limits = [-150 150];
            app.YawEditField.RoundFractionalValues = 'on';
            app.YawEditField.ValueDisplayFormat = '%11.4g Deg';
            app.SendHeadPositionButton = uibutton(app.SetheadpositionPanel, 'push');
            app.SendHeadPositionButton.ButtonPushedFcn = createCallbackFcn(app, @SendHeadPositionButtonPushed, true);
            app.SendHeadPositionButton.Enable = 'off';
            app.SendHeadPositionButton.Text = 'Send';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.VelocityGaugeLabel = uilabel(app.Panel);
            app.VelocityGaugeLabel.Text = 'Velocity';
            app.VelocityGauge = uigauge(app.Panel, 'semicircular');
            app.VelocityGauge.Limits = [-2 2];
            app.TurnRateGaugeLabel = uilabel(app.Panel);
            app.TurnRateGaugeLabel.Text = 'Turn Rate';
            app.TurnRateGauge = uigauge(app.Panel, 'semicircular');
            app.TurnRateGauge.Limits = [-2 2];
            app.KeyboardControlPanel = uipanel(app.UIFigure);
            app.KeyboardControlPanel.Title = 'Keyboard Control';
            app.KeyboardVelocityButton = uibutton(app.KeyboardControlPanel, 'state');
            app.KeyboardVelocityButton.ValueChangedFcn = createCallbackFcn(app, @KeyboardVelocityButtonValueChanged, true);
            app.KeyboardVelocityButton.Text = 'Keyboard Velocity';
            app.PitchLabel = uilabel(app.KeyboardControlPanel);
            app.PitchLabel.Text = 'Pitch:';
            app.YawLabel = uilabel(app.KeyboardControlPanel);
            app.YawLabel.Text = 'Yaw:';
            app.VelocityLabel = uilabel(app.KeyboardControlPanel);
            app.VelocityLabel.Text = 'Velocity:';
            app.TurnRateLabel = uilabel(app.KeyboardControlPanel);
            app.TurnRateLabel.Text = 'TurnRate:';
            app.ShowPitch = uilabel(app.KeyboardControlPanel);
            app.ShowPitch.Text = '- deg';
            app.ShowYaw = uilabel(app.KeyboardControlPanel);
            app.ShowYaw.Text = '- deg';
            app.ShowVel = uilabel(app.KeyboardControlPanel);
            app.ShowVel.Text = '- m/s';
            app.ShowTurn = uilabel(app.KeyboardControlPanel);
            app.ShowTurn.Text = '- m/s';
            app.SpeakPanel = uipanel(app.UIFigure);
            app.SpeakPanel.Title = 'Speak';
            app.SpeakTextArea = uitextarea(app.SpeakPanel);
            app.SendButton = uibutton(app.SpeakPanel, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.VolumeSliderLabel = uilabel(app.SpeakPanel);
            app.VolumeSliderLabel.Text = 'Volume';
            app.VolumeSlider = uislider(app.SpeakPanel);
            app.VolumeSlider.ValueChangedFcn = createCallbackFcn(app, @VolumeSliderValueChanged, true);
            app.VolumeSlider.Value = 50;
            app.ImageRight = uiimage(app.UIFigure);
            app.ImageRightDropDown_2Label = uilabel(app.UIFigure);
            app.ImageRightDropDown_2Label.Text = 'Image Right';
            app.ImageRightDropDown = uidropdown(app.UIFigure);
            app.ImageRightDropDown.Items = {'Not available'};
            app.ImageRightDropDown.ItemsData = {'1', '2', '3'};
            app.ImageRightDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageRightDropDownValueChanged, true);
            app.ImageRightDropDown.Value = '1';
            app.GotopositionPanel = uipanel(app.UIFigure);
            app.GotopositionPanel.Title = 'Go to position';
            app.GotopositionPanel.Visible = 'off';
            app.GoButton = uibutton(app.GotopositionPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.XEditFieldLabel = uilabel(app.GotopositionPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GotopositionPanel, 'numeric');
            app.XEditField.Limits = [-10 10];
            app.XEditField.ValueDisplayFormat = '%11.4g m';
            app.YEditFieldLabel = uilabel(app.GotopositionPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GotopositionPanel, 'numeric');
            app.YEditField.Limits = [-10 10];
            app.YEditField.ValueDisplayFormat = '%11.4g m';
            app.YawLabel_2 = uilabel(app.GotopositionPanel);
            app.YawLabel_2.Text = 'Yaw*';
            app.YawEditField_pos = uieditfield(app.GotopositionPanel, 'numeric');
            app.YawEditField_pos.ValueDisplayFormat = '%11g deg';
            app.CheckBox = uicheckbox(app.GotopositionPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.ImageLeft = uiimage(app.UIFigure);
            app.ImageLeftDropDownLabel = uilabel(app.UIFigure);
            app.ImageLeftDropDownLabel.Text = 'Image Left';
            app.ImageLeftDropDown = uidropdown(app.UIFigure);
            app.ImageLeftDropDown.Items = {'Not available'};
            app.ImageLeftDropDown.ItemsData = {'1', '2', '3'};
            app.ImageLeftDropDown.ValueChangedFcn = createCallbackFcn(app, @ImageLeftDropDownValueChanged, true);
            app.ImageLeftDropDown.Value = '1';
            app.SensorstoReadPanel = uipanel(app.UIFigure);
            app.SensorstoReadPanel.Title = 'Sensors to Read';
            app.VelocityPoseButton = uibutton(app.SensorstoReadPanel, 'state');
            app.VelocityPoseButton.ValueChangedFcn = createCallbackFcn(app, @VelocityPoseButtonValueChanged, true);
            app.VelocityPoseButton.Text = 'Velocity & Pose';
            app.SurroundingsButton = uibutton(app.SensorstoReadPanel, 'state');
            app.SurroundingsButton.ValueChangedFcn = createCallbackFcn(app, @SurroundingsButtonValueChanged, true);
            app.SurroundingsButton.Text = 'Surroundings';
            app.BaseIMUButton = uibutton(app.SensorstoReadPanel, 'state');
            app.BaseIMUButton.Enable = 'off';
            app.BaseIMUButton.Text = 'Base IMU';
            app.HeadIMUButton = uibutton(app.SensorstoReadPanel, 'state');
            app.HeadIMUButton.Enable = 'off';
            app.HeadIMUButton.Text = 'Head IMU';
            app.BasePoseandIMUreadingPanel = uipanel(app.UIFigure);
            app.BasePoseandIMUreadingPanel.Title = 'Base Pose and IMU reading';
            app.XLabel = uilabel(app.BasePoseandIMUreadingPanel);
            app.XLabel.Text = 'X:';
            app.YLabel = uilabel(app.BasePoseandIMUreadingPanel);
            app.YLabel.Text = 'Y:';
            app.ThLabel = uilabel(app.BasePoseandIMUreadingPanel);
            app.ThLabel.Text = 'Th:';
            app.xPoseLabel = uilabel(app.BasePoseandIMUreadingPanel);
            app.xPoseLabel.Text = '- m';
            app.yPoseLabel = uilabel(app.BasePoseandIMUreadingPanel);
            app.yPoseLabel.Text = '- m';
            app.thPoseLabel = uilabel(app.BasePoseandIMUreadingPanel);
            app.thPoseLabel.Text = '- deg';
            app.RollLabel = uilabel(app.BasePoseandIMUreadingPanel);
            app.RollLabel.Text = 'Roll:';
            app.PitchLabel_2 = uilabel(app.BasePoseandIMUreadingPanel);
            app.PitchLabel_2.Text = 'Pitch:';
            app.YawLabel_3 = uilabel(app.BasePoseandIMUreadingPanel);
            app.YawLabel_3.Text = 'Yaw:';
            app.thPoseLabel_2 = uilabel(app.BasePoseandIMUreadingPanel);
            app.thPoseLabel_2.Text = '- deg';
            app.thPoseLabel_3 = uilabel(app.BasePoseandIMUreadingPanel);
            app.thPoseLabel_3.Text = '- deg';
            app.thPoseLabel_4 = uilabel(app.BasePoseandIMUreadingPanel);
            app.thPoseLabel_4.Text = '- deg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoomoDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end